classdef app1GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        STOPButton              matlab.ui.control.Button
        GPSButton               matlab.ui.control.Button
        UploadButton            matlab.ui.control.Button
        RightPanel              matlab.ui.container.Panel
        No_stepsEditField       matlab.ui.control.EditField
        No_stepsEditFieldLabel  matlab.ui.control.Label
        UIAxes2_2               matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        go;
        oldTime;
        steps;
        afis
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
             app.steps = 0;
             app.go=true;
             app.afis=false;
             app.oldTime = datetime(now,'ConvertFrom','datenum');
           while(app.go)
     %Read data from ThingSpeak Cloud          
            [lat,lon,alt,acc,time]=read();
            
     % put a pause on taking the data due to ThingSpeak limitations
            

     %define projected coordinate reference system object (CRS)
     %introduce ESPG code for Cluj-Napoca, Romania(3857)
          %  proj = projcrs(3857);

     %transform the latitude-longitude coordinates specified by lat 
     % and lon to the x and y map coordinates in the projected 
     % coordinate reference system specified by proj
            
            %[x,y]=projfwd(proj,lat,lon);
            acc2 = thingSpeakRead(1944434,'Fields',5,'ReadKey','1JZ20CRZYUOEPCCC','NumPoints',1000,'Timeout',50,'OutputFormat','matrix');

            plot(app.UIAxes,time,lat)
            plot(app.UIAxes2,time,lon)
            plot(app.UIAxes_2,time,acc2)
            plot(app.UIAxes2_2,time,alt)


         %count number of steps
%oldTime describes value from time array corresponding to the moment when
% you pressed the upload button 
%posTime is the first indice corresponding to the elements in the time
%array defined above
    posTime = find(time>app.oldTime, 1);    

    flag=isempty(posTime);

if(flag==false)
%     lastAcc = acc(posTime:end);
%     a = zeros(1,length(lastAcc));
%     for i = 1:length(lastAcc)    
%             a(1,i) = (convertCharsToStrings(lastAcc{i}));       %if A is a Stringarray
%     end
    app.oldTime = time(end);

     a = acc(posTime:end);
     % Use FINDPEAKS to determine the local maxima.
       % minPeakHeight = std(a);
       % [pks,locs] = findpeaks(a, 'MINPEAKHEIGHT', minPeakHeight);
       [pks,locs] = findpeaks(a);
%          plot(app.UIAxes_2,time(locs),pks,'r', 'Marker', 'v')
     %The number of steps taken is the number of peaks: 
      app.steps =  app.steps+numel(pks);
end     
 app.No_stepsEditField.Value= num2str(app.steps);

if(app.steps>=10 && app.afis==false) %am verificat ca apare pop-up-ul
    app.afis=true;
    msgbox('Congrats! You made more than 10 steps','alarm');
end
pause(10);

end

        end

        % Button pushed function: GPSButton
        function plot_gps_app(app, event)
          [lat,lon,alt]=read(); 
         plot_gps(lat, lon, alt);   
        end

        % Callback function: No_stepsEditField, RightPanel
        function No_stepsEditFieldValueChanged(app, event)
        
        end

        % Value changing function: No_stepsEditField
        function No_stepsEditFieldValueChanging(app, event)

 
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)

            app.go = false;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {490, 490};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {110, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UploadButton = uibutton(app.LeftPanel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.GPSButton = uibutton(app.LeftPanel, 'push');
            app.GPSButton.ButtonPushedFcn = createCallbackFcn(app, @plot_gps_app, true);
            app.GPSButton.Text = 'GPS';
            app.STOPButton = uibutton(app.LeftPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.ButtonDownFcn = createCallbackFcn(app, @No_stepsEditFieldValueChanged, true);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Latitude')
            xlabel(app.UIAxes, 'Date')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Longitude')
            xlabel(app.UIAxes2, 'Date')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Acceleration')
            xlabel(app.UIAxes_2, 'Date')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2_2 = uiaxes(app.RightPanel);
            title(app.UIAxes2_2, 'Altitude')
            xlabel(app.UIAxes2_2, 'Date')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.No_stepsEditFieldLabel = uilabel(app.RightPanel);
            app.No_stepsEditFieldLabel.Text = 'No_steps:';
            app.No_stepsEditField = uieditfield(app.RightPanel, 'text');
            app.No_stepsEditField.ValueChangedFcn = createCallbackFcn(app, @No_stepsEditFieldValueChanged, true);
            app.No_stepsEditField.ValueChangingFcn = createCallbackFcn(app, @No_stepsEditFieldValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end